classdef tubes1gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        GridLayout        matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        Image             matlab.ui.control.Image
        TabGroup2         matlab.ui.container.TabGroup
        GrayscaleTab      matlab.ui.container.Tab
        GridLayout4       matlab.ui.container.GridLayout
        UIAxes            matlab.ui.control.UIAxes
        RedTab            matlab.ui.container.Tab
        GridLayout4_4     matlab.ui.container.GridLayout
        UIAxesR           matlab.ui.control.UIAxes
        GreenTab          matlab.ui.container.Tab
        GridLayout4_3     matlab.ui.container.GridLayout
        UIAxesG           matlab.ui.control.UIAxes
        BlueTab           matlab.ui.container.Tab
        GridLayout4_2     matlab.ui.container.GridLayout
        UIAxesB           matlab.ui.control.UIAxes
        GridLayout2       matlab.ui.container.GridLayout
        ImageInputButton  matlab.ui.control.Button
        Tab2              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageInputButton
        function ImageInputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                fullFileName = fullfile(path, file);
                
                % Read the image
                img = imread(fullFileName);
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = fullFileName;

                imgprcs = ImageProcessor;

                % If the image is RGB, convert it to grayscale for histogram analysis
                if size(img, 3) == 3
                    rgbHistogram = imgprcs.calculateColoredHistogram(img);
                    
                    % Plot red histogram
                    bar(app.UIAxesR, rgbHistogram(1, :));
                    title(app.UIAxesR, 'Image Histogram');
                    xlabel(app.UIAxesR, 'Pixel Intensity');
                    ylabel(app.UIAxesR, 'Frequency');
                    
                    % Plot green histogram
                    bar(app.UIAxesG, rgbHistogram(2, :));
                    title(app.UIAxesG, 'Image Histogram');
                    xlabel(app.UIAxesG, 'Pixel Intensity');
                    ylabel(app.UIAxesG, 'Frequency');
                    
                    % Plot blue histogram
                    bar(app.UIAxesB, rgbHistogram(3, :));
                    title(app.UIAxesB, 'Image Histogram');
                    xlabel(app.UIAxesB, 'Pixel Intensity');
                    ylabel(app.UIAxesB, 'Frequency');
                else
                    % Compute the histogram of the grayscale image
                    histogram = imgprcs.calculateGrayscaledHistogram(img);
                
                    % Plot the histogram in app.UIAxes
                    bar(app.UIAxes, histogram);
                    title(app.UIAxes, 'Image Histogram');
                    xlabel(app.UIAxes, 'Pixel Intensity');
                    ylabel(app.UIAxes, 'Frequency');
                end
                
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ImageInputButton = uibutton(app.GridLayout2, 'push');
            app.ImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @ImageInputButtonPushed, true);
            app.ImageInputButton.Text = 'Image Input';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.GrayscaleTab = uitab(app.TabGroup2);
            app.GrayscaleTab.Title = 'Grayscale';
            app.GridLayout4 = uigridlayout(app.GrayscaleTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RedTab = uitab(app.TabGroup2);
            app.RedTab.Title = 'Red';
            app.GridLayout4_4 = uigridlayout(app.RedTab);
            app.UIAxesR = uiaxes(app.GridLayout4_4);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.GreenTab = uitab(app.TabGroup2);
            app.GreenTab.Title = 'Green';
            app.GridLayout4_3 = uigridlayout(app.GreenTab);
            app.UIAxesG = uiaxes(app.GridLayout4_3);
            title(app.UIAxesG, 'Title')
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            zlabel(app.UIAxesG, 'Z')
            app.BlueTab = uitab(app.TabGroup2);
            app.BlueTab.Title = 'Blue';
            app.GridLayout4_2 = uigridlayout(app.BlueTab);
            app.UIAxesB = uiaxes(app.GridLayout4_2);
            title(app.UIAxesB, 'Title')
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            zlabel(app.UIAxesB, 'Z')
            app.Image = uiimage(app.GridLayout3);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes1gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end